classdef robotControlGUi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControllerPanel                matlab.ui.container.Panel
        ControllerParametersPanelP     matlab.ui.container.Panel
        PEditFieldLabel                matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        IEditFieldLabel                matlab.ui.control.Label
        IEditField                     matlab.ui.control.NumericEditField
        DEditFieldLabel                matlab.ui.control.Label
        DEditField                     matlab.ui.control.NumericEditField
        ControllerTypePanel            matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        UploadControllerButton         matlab.ui.control.Button
        LiveDataPanel                  matlab.ui.container.Panel
        UIAxesGyro                     matlab.ui.control.UIAxes
        UIAxesAccel                    matlab.ui.control.UIAxes
        UIAxesGyroTime                 matlab.ui.control.UIAxes
        DeviceConnectionPanel          matlab.ui.container.Panel
        SelectSerialPortDropDownLabel  matlab.ui.control.Label
        SelectSerialPortDropDown       matlab.ui.control.DropDown
        ConnectButton                  matlab.ui.control.Button
        PleaseConnecttoadeviceLamp     matlab.ui.control.Lamp
        RefreshPortsButton             matlab.ui.control.Button
        ConnectionStatusText           matlab.ui.control.Label
        SaveDataPanel                  matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        DirectoryEditFieldLabel        matlab.ui.control.Label
        DirectoryEditField             matlab.ui.control.EditField
        ChooseDirectoryButton          matlab.ui.control.Button
    end


    properties (Access = private)
        serial_port;            % Arduino/bluetooth serial port
        serial_read_timer;      % 10Hz timer used to grab data from the bluetooth device & update graphs 
        all_roll = [];               % Description
        all_pitch = [];              % Description
    end

    methods (Access = private)        

        function results = plotCircle(app, axes, radius, colour)
            x = 0;
            y = 0;
            ang=0:0.01:2*pi; 
            xp=radius*cos(ang);
            yp=radius*sin(ang);
            plot(axes, x+xp,y+yp, 'Color', colour);
        end
        
        function results = plotGyroTarget(app)
        
            plotCircle(app, app.UIAxesGyro, 45, [1 0 0]);
            plotCircle(app, app.UIAxesGyro, 5, [0 0.8 0]);
        end
    
        function results = updateAccelerometerTimePlot(app)
            
        end
      
        function results = updateGyroTimePlot(app)
           plot(app.UIAxesGyroTime, [app.all_roll' app.all_pitch']);
           legend(app.UIAxesGyroTime,'Roll','Pitch');
        end
        
        function results = updateGyroPlot(app, x, y)
            cla(app.UIAxesGyro);
            plotGyroTarget(app);
            scatter(app.UIAxesGyro,x,y, [], [0 0 1]);
        end

        function serialReadTimerFunction(app,~,~)
            
            fwrite(app.serial_port, uint8(1)); 
            data = fgetl(app.serial_port);
            
            if (length(data) > 1)
                pitch = data(1:strfind(data, ",")-1);
                roll = data(strfind(data, ",")+1:end);
                
                pitch = str2double(pitch);
                roll = str2double(roll); 
                
                app.all_pitch = [app.all_pitch pitch]; 
                app.all_roll = [app.all_roll roll]; 
                
                app.updateGyroPlot(pitch, roll); 
                app.updateGyroTimePlot(); 
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup graph axes 
            app.UIAxesAccel.YLim = [-10, 10];
            app.UIAxesGyro.YLim = [-90, 90];
            app.UIAxesGyro.XLim = [-90, 90];
            
            % Plot default data on graphs 
            app.updateGyroPlot(0,0);
            
            % List serial ports 
            serial_ports = seriallist;
            app.SelectSerialPortDropDown.Items = serial_ports;
            
            hold(app.UIAxesGyro);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
            if (strcmp(value, 'P'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = false;
               app.DEditField.Enable = false;
            elseif (strcmp(value, 'PI'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = true;
               app.DEditField.Enable = false; 
            elseif (strcmp(value, 'PD'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = false;
               app.DEditField.Enable = true;
            elseif (strcmp(value, 'PID'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = true;
               app.DEditField.Enable = true;
            end
        end

        % Value changed function: SelectSerialPortDropDown
        function SelectSerialPortDropDownValueChanged(app, event)
            value = app.SelectSerialPortDropDown.Value;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            try
                stop(app.serial_read_timer);   
            catch
               ; 
            end
            
            serial_port = app.SelectSerialPortDropDown.Value;
            app.serial_port = serial(serial_port, 'BaudRate', 115200);
            
            % Try to open the port & catch errors
            try
                fopen(app.serial_port);
            catch ME
               disp("Error: " + ME.identifier);  
               app.PleaseConnecttoadeviceLamp.Color = [1 0 0];
               app.ConnectionStatusText.Text = 'Failed to open that port'; 
               return; 
            end

            % Check if the port is open after the attempt to open 
            if (strcmp(app.serial_port.Status,'open'))
                app.PleaseConnecttoadeviceLamp.Color = [0 1 0]; 
                app.ConnectionStatusText.Text = 'Connected'; 
            else
                app.PleaseConnecttoadeviceLamp.Color = [1 0 0]
                app.ConnectionStatusText.Text = 'Failed to open that port'; 
                return;
            end

            % Setup MATLAB Timer at 100Hz to poll bluetooth device
            app.serial_read_timer = timer('Period', 0.01, 'ExecutionMode', 'FixedSpacing', 'TasksToExecute', Inf, 'StartDelay', 2); 
            app.serial_read_timer.TimerFcn = @(~,~)app.serialReadTimerFunction(app);
            start(app.serial_read_timer); 
        end

        % Button pushed function: RefreshPortsButton
        function RefreshPortsButtonPushed(app, event)
            % List serial ports 
            serial_ports = seriallist;
            app.SelectSerialPortDropDown.Items = serial_ports;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControllerPanel = uipanel(app.UIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.ControllerParametersPanelP = uipanel(app.ControllerPanel);
            app.ControllerParametersPanelP.Title = 'Controller Parameters';
            app.PEditFieldLabel = uilabel(app.ControllerParametersPanelP);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ControllerParametersPanelP, 'numeric');
            app.IEditFieldLabel = uilabel(app.ControllerParametersPanelP);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.ControllerParametersPanelP, 'numeric');
            app.DEditFieldLabel = uilabel(app.ControllerParametersPanelP);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ControllerParametersPanelP, 'numeric');
            app.ControllerTypePanel = uipanel(app.ControllerPanel);
            app.ControllerTypePanel.Title = 'Controller Type';
            app.ListBox = uilistbox(app.ControllerTypePanel);
            app.ListBox.Items = {'P', 'PI', 'PD', 'PID', 'Fuzzy Logic'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'P';
            app.UploadControllerButton = uibutton(app.ControllerPanel, 'push');
            app.UploadControllerButton.Text = 'Upload Controller';
            app.LiveDataPanel = uipanel(app.UIFigure);
            app.LiveDataPanel.Title = 'Live Data';
            app.UIAxesGyro = uiaxes(app.LiveDataPanel);
            title(app.UIAxesGyro, 'Gyroscope')
            xlabel(app.UIAxesGyro, 'X')
            ylabel(app.UIAxesGyro, 'Y')
            app.UIAxesAccel = uiaxes(app.LiveDataPanel);
            title(app.UIAxesAccel, 'Accelerometer')
            xlabel(app.UIAxesAccel, 'Time')
            ylabel(app.UIAxesAccel, 'Acceleration')
            app.UIAxesGyroTime = uiaxes(app.LiveDataPanel);
            title(app.UIAxesGyroTime, 'Gyroscope With Time')
            xlabel(app.UIAxesGyroTime, 'Time')
            ylabel(app.UIAxesGyroTime, 'Gyro Value')
            app.DeviceConnectionPanel = uipanel(app.UIFigure);
            app.DeviceConnectionPanel.Title = 'Device Connection';
            app.SelectSerialPortDropDownLabel = uilabel(app.DeviceConnectionPanel);
            app.SelectSerialPortDropDownLabel.Text = 'Select Serial Port';
            app.SelectSerialPortDropDown = uidropdown(app.DeviceConnectionPanel);
            app.SelectSerialPortDropDown.Items = {};
            app.SelectSerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSerialPortDropDownValueChanged, true);
            app.SelectSerialPortDropDown.Value = {};
            app.ConnectButton = uibutton(app.DeviceConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PleaseConnecttoadeviceLamp = uilamp(app.DeviceConnectionPanel);
            app.RefreshPortsButton = uibutton(app.DeviceConnectionPanel, 'push');
            app.RefreshPortsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshPortsButtonPushed, true);
            app.RefreshPortsButton.Text = 'Refresh Ports';
            app.ConnectionStatusText = uilabel(app.DeviceConnectionPanel);
            app.ConnectionStatusText.Text = 'Please connect to a serial port';
            app.SaveDataPanel = uipanel(app.UIFigure);
            app.SaveDataPanel.Title = 'Save Data';
            app.SaveButton = uibutton(app.SaveDataPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.DirectoryEditFieldLabel = uilabel(app.SaveDataPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SaveDataPanel, 'text');
            app.ChooseDirectoryButton = uibutton(app.SaveDataPanel, 'push');
            app.ChooseDirectoryButton.Text = 'Choose Directory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = robotControlGUi

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end